classdef FinalRaceEngineer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TimerEditField                matlab.ui.control.EditField
        TimerEditFieldLabel           matlab.ui.control.Label
        StartTimerButton              matlab.ui.control.Button
        CurrentplotLabel              matlab.ui.control.Label
        VoltageplotLabel              matlab.ui.control.Label
        LapTree                       matlab.ui.container.CheckBoxTree
        Lap1Node                      matlab.ui.container.TreeNode
        WhichlapshouldbeplottedLabel  matlab.ui.control.Label
        CurrentTree                   matlab.ui.container.CheckBoxTree
        SupercapsOverallNode_2        matlab.ui.container.TreeNode
        SupercapsChargeNode           matlab.ui.container.TreeNode
        BeforeMotorControlNode_2      matlab.ui.container.TreeNode
        DCDCNode                      matlab.ui.container.TreeNode
        VoltageTree                   matlab.ui.container.CheckBoxTree
        SupercapsOverallNode          matlab.ui.container.TreeNode
        BeforeMotorControlNode        matlab.ui.container.TreeNode
        DCDCvoltageNode               matlab.ui.container.TreeNode
        LapField                      matlab.ui.control.NumericEditField
        LapEditFieldLabel             matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.Button
        Baudrate                      matlab.ui.control.DropDown
        serialPort                    matlab.ui.control.DropDown
        PortLabel                     matlab.ui.control.Label
        BaudrateDropDownLabel         matlab.ui.control.Label
        voltageUIAxes                 matlab.ui.control.UIAxes
        currentUIAxes                 matlab.ui.control.UIAxes
        velUIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serialDevice % Serial port object
        myTimer %Timer
        Timer % Timer object
        TimerObject % Timer object
        StartTime % Start time
        velocity = {}; %Cell array to store velocity readings of each lap
        current = {}; %Cell array to store current readings of each lap
        voltage = {}; %Cell array to store voltage readings of each lap
        %Voltage values
        superCapsOverallVoltage = {}; %Cell array to store the supercaps overall voltage readings of each lap
        beforeMotorControlVoltage = {}; %Cell array to store the before motor control voltage readings of each lap
        DCDCVoltage = {}; %Cell array to store the DC DC voltage readings of each lap

        %Current values
        superCapsOverallCurrent = {}; %Cell array to store the supercaps overall current readings of each lap
        superCapsChargeCurrent = {}; %Cell array to store the supercaps charge current readings of each lap
        beforeMotorControlCurrent = {}; %Cell array to store the before motor control current readings of each lap
        DCDCCurrent = {}; %Cell array to store the DC DC current readings of each lap
    end
    
    methods (Access = private)
        function myTimerCallback(app, src, event)
            if (src.NumBytesAvailable > 0)
                        dataStr = readline(src);  % Reads data from the serial port until a newline is received
                        %disp(['Received: ', dataStr]);  % This will show what exactly is being received
                        % Remove non-ASCII characters except '§'
                        dataStr = regexprep(dataStr, '[\s>]|[^\x20-\x7E§]', '');
                        %dataStr = regexprep(dataStr, '[\s]', '');
                        %dataStr = regexprep(dataStr, '[^\x20-\x7E§]', '');
                        %dataStr = regexprep(dataStr, '[ <]', '');
                        %disp(['Cleaned Data: ', dataStr]);  % Optionally display cleaned data

                        try
                            % Split the data into its components
                            splitData = strsplit(dataStr, '§');  % Split based on the '§' delimiter
                            if numel(splitData) == 2
                                varNameValue = splitData{1};
                                %varUnit = splitData{2};

                                % Further split to separate variable name and value
                                nameValueSplit = strsplit(varNameValue, ':');
                                    if numel(nameValueSplit) == 2
                                        varName = nameValueSplit{1};
                                        varValue = str2double(nameValueSplit{2});  % Convert value to double

                                        % Print the parsed data in the terminal
                                        %fprintf('Variable Name: %s\n', varName);
                                        %disp(['Var name: ', varName]);
                                        %fprintf('Variable Value: %.2f\n', string(varValue));
                                        %fprintf('Unit: %s\n', varUnit);

                                        if strcmp(varName, 'Lap#')
                                            app.LapField.Value = varValue;
                                            handleNewLap(app);

                                        elseif strcmp(varName, 'Velocity')
                                            %disp('This is a velocity reading');
                                            addVelocityValue(app, varValue);
                                            updateVelocityPlot(app);

                                        %Supercaps Overall voltage update
                                        elseif strcmp(varName, 'SCOV')
                                            addSCOVoltageValue(app, string(varValue));
                                            updateVoltagePlot(app);

                                        %before motor control voltage update
                                        elseif strcmp(varName, 'BMCV')
                                            addBMCVoltageValue(app, string(varValue));
                                            updateVoltagePlot(app);

                                        %DC DC voltage update
                                        elseif strcmp(varName, 'DCDCV')
                                            addDCDCVoltageValue(app, string(varValue));
                                            updateVoltagePlot(app);

                                        %Supercaps Overall current update
                                        elseif strcmp(varName, 'Curr1')
                                            addSCOCurrentValue(app, string(varValue));
                                            updateCurrentPlot(app);

                                        %Supercaps Charge current update
                                        elseif strcmp(varName, 'Curr2')
                                            addSCCCurrentValue(app, string(varValue));
                                            updateCurrentPlot(app);

                                        %Before Motor Control Current
                                        %update
                                        elseif strcmp(varName, 'Curr3')
                                            addBMCCurrentValue(app, string(varValue));
                                            updateCurrentPlot(app);

                                        %DC DC Current update
                                        elseif strcmp(varName, 'DCDCC')
                                            addDCDCCurrentValue(app, string(varValue));
                                            updateCurrentPlot(app);
                                         

                                        % elseif strcmp(varName, 'current')
                                        %     addCurrentValue(app, varValue);
                                        %     updateCurrentPlot(app);

                                        % elseif strcmp(varName, 'voltage')
                                        %     disp('Lon if true');
                                        %     addVoltageValue(app, varValue);
                                        %     updateVoltagePlot(app);
                                        end
                                    else
                                        disp('Error: Variable name and value not in expected format.');
                                    end
                            else
                                    disp('Error: Data not in expected format.');
                            end
                        catch
                            disp(['Error processing data: ', dataStr]);
                        end
            end
        end

        function addVelocityValue(app, value)
            lapIndex = app.LapField.Value; % Get the current lap index from the LapField
    
            if length(app.velocity) < lapIndex || isempty(app.velocity{lapIndex})
                % If the current lap's cell doesn't exist, initialize it with the value
                app.velocity{lapIndex} = value;
            else
                % Otherwise, append the value to the existing cell data
                app.velocity{lapIndex} = [app.velocity{lapIndex}, value];
            end
        end

        function addCurrentValue(app, value)
            lapIndex = app.LapField.Value; % Get the current lap index from the LapField

            if length(app.current) < lapIndex || isempty(app.current{lapIndex})
                % If the current lap's cell doesn't exist, initialize it with the value
                app.current{lapIndex} = value;
            else
                % Otherwise, append the value to the existing cell data
                app.current{lapIndex} = [app.current{lapIndex}, value];
            end
        end

        function addVoltageValue(app, value)
            lapIndex = app.LapField.Value; % Get the current lap index from the LapField

            if length(app.voltage) < lapIndex || isempty(app.voltage{lapIndex})
                % If the current lap's cell doesn't exist, initialize it with the value
                app.voltage{lapIndex} = value;
            else
                % Otherwise, append the value to the existing cell data
                app.voltage{lapIndex} = [app.voltage{lapIndex}, value];
            end
        end

        function addSCOVoltageValue(app, value)
            lapIndex = app.LapField.Value; % Get the current lap index from the LapField

            if length(app.superCapsOverallVoltage) < lapIndex || isempty(app.superCapsOverallVoltage{lapIndex})
                % If the current lap's cell doesn't exist, initialize it with the value
                app.superCapsOverallVoltage{lapIndex} = value;
            else
                % Otherwise, append the value to the existing cell data
                app.superCapsOverallVoltage{lapIndex} = [app.superCapsOverallVoltage{lapIndex}, value];
            end
        end

            function addBMCVoltageValue(app, value)
                lapIndex = app.LapField.Value; % Get the current lap index from the LapField

                if length(app.beforeMotorControlVoltage) < lapIndex || isempty(app.beforeMotorControlVoltage{lapIndex})
                    % If the current lap's cell doesn't exist, initialize it with the value
                    app.beforeMotorControlVoltage{lapIndex} = value;
                else
                    % Otherwise, append the value to the existing cell data
                    app.beforeMotorControlVoltage{lapIndex} = [app.beforeMotorControlVoltage{lapIndex}, value];
                end
            end

            function addDCDCVoltageValue(app, value)
                lapIndex = app.LapField.Value; % Get the current lap index from the LapField

                if length(app.DCDCVoltage) < lapIndex || isempty(app.DCDCVoltage{lapIndex})
                    % If the current lap's cell doesn't exist, initialize it with the value
                    app.DCDCVoltage{lapIndex} = value;
                else
                    % Otherwise, append the value to the existing cell data
                    app.DCDCVoltage{lapIndex} = [app.DCDCVoltage{lapIndex}, value];
                end
            end

            function addSCOCurrentValue(app, value)
                lapIndex = app.LapField.Value; % Get the current lap index from the LapField

                if length(app.superCapsOverallCurrent) < lapIndex || isempty(app.superCapsOverallCurrent{lapIndex})
                    % If the current lap's cell doesn't exist, initialize it with the value
                    app.superCapsOverallCurrent{lapIndex} = value;
                else
                    % Otherwise, append the value to the existing cell data
                    app.superCapsOverallCurrent{lapIndex} = [app.superCapsOverallCurrent{lapIndex}, value];
                end
            end

            function addSCCCurrentValue(app, value)
                lapIndex = app.LapField.Value; % Get the current lap index from the LapField

                if length(app.superCapsChargeCurrent) < lapIndex || isempty(app.superCapsChargeCurrent{lapIndex})
                    % If the current lap's cell doesn't exist, initialize it with the value
                    app.superCapsChargeCurrent{lapIndex} = value;
                else
                    % Otherwise, append the value to the existing cell data
                    app.superCapsChargeCurrent{lapIndex} = [app.superCapsChargeCurrent{lapIndex}, value];
                end
            end

            function addBMCCurrentValue(app, value)
                lapIndex = app.LapField.Value; % Get the current lap index from the LapField

                if length(app.beforeMotorControlCurrent) < lapIndex || isempty(app.beforeMotorControlCurrent{lapIndex})
                    % If the current lap's cell doesn't exist, initialize it with the value
                    app.beforeMotorControlCurrent{lapIndex} = value;
                else
                    % Otherwise, append the value to the existing cell data
                    app.beforeMotorControlCurrent{lapIndex} = [app.beforeMotorControlCurrent{lapIndex}, value];
                end
            end

            function addDCDCCurrentValue(app, value)
                lapIndex = app.LapField.Value; % Get the current lap index from the LapField

                if length(app.DCDCCurrent) < lapIndex || isempty(app.DCDCCurrent{lapIndex})
                    % If the current lap's cell doesn't exist, initialize it with the value
                    app.DCDCCurrent{lapIndex} = value;
                else
                    % Otherwise, append the value to the existing cell data
                    app.DCDCCurrent{lapIndex} = [app.DCDCCurrent{lapIndex}, value];
                end
            end


        function updateVelocityPlot(app)
            cla(app.velUIAxes);  % Clear the current axes
            hold(app.velUIAxes, 'on');
            checkedNodes = app.LapTree.CheckedNodes;  % Get the list of checked nodes

            % Loop over each checked node
            for i = 1:numel(checkedNodes)
                node = checkedNodes(i);  % Get each checked node
                lapIndex = node.NodeData;  % Assuming NodeData stores the corresponding lap index

                % Ensure lapIndex is valid and within bounds
                if isnumeric(lapIndex) && lapIndex > 0 && lapIndex <= length(app.velocity)
                    % Check if the lap data exists and is not empty
                    if ~isempty(app.velocity{lapIndex})
                        plot(app.velUIAxes, app.velocity{lapIndex}, 'DisplayName', sprintf('Lap %d', lapIndex), 'LineWidth',4);
                    end
                end
            end
            hold(app.velUIAxes, 'off');
            legend(app.velUIAxes, 'Location', 'best');
            title(app.velUIAxes, 'Velocity vs Measurements');
            xlabel(app.velUIAxes, 'Measurements');
            ylabel(app.velUIAxes, 'Velocity (m/s)');
        end

        function updateCurrentPlot(app)
            cla(app.currentUIAxes);  % Clear the current axes
            hold(app.currentUIAxes, 'on');
            checkedLapNodes = app.LapTree.CheckedNodes;  % Get the list of checked nodes from the LapTree
            checkedCurrentParameters = app.CurrentTree.CheckedNodes;  % Get the list of checked voltage parameters


            % Loop over each checked lap node
            for i = 1:numel(checkedLapNodes)
                lapNode = checkedLapNodes(i);  % Get each checked lap node
                lapIndex = lapNode.NodeData;  % Assuming NodeData stores the corresponding lap index

                % Ensure lapIndex is valid and within bounds
                if isnumeric(lapIndex) && lapIndex > 0 && lapIndex <= length(app.velocity)
                    % Check if the lap data exists and is not empty

                    % Loop over each checked parameter node
                    for j = 1:numel(checkedCurrentParameters)
                        parameterNode = checkedCurrentParameters(j);
                        parameterIndex = parameterNode.NodeData;  % NodeData here should correspond to the parameter index

                        switch parameterIndex
                            case 1  % Supercaps Overall Current
                                if ~isempty(app.superCapsOverallCurrent{lapIndex})
                                    plot(app.currentUIAxes, str2double(app.superCapsOverallCurrent{lapIndex}), 'DisplayName', sprintf('SCO Lap %d', lapIndex), 'LineWidth',4);
                                end
                            case 2  % Supercaps Charge Current
                                if ~isempty(app.superCapsChargeCurrent{lapIndex})
                                    plot(app.currentUIAxes, str2double(app.superCapsChargeCurrent{lapIndex}), 'DisplayName', sprintf('SCC Lap %d', lapIndex), 'LineWidth',4);
                                end
                            case 3  % Before Motor Control Current
                                if ~isempty(app.beforeMotorControlCurrent{lapIndex})
                                    plot(app.currentUIAxes, str2double(app.beforeMotorControlCurrent{lapIndex}), 'DisplayName', sprintf('BMC Lap %d', lapIndex), 'LineWidth',4);
                                end
                            % case 4  % DC DC Current
                            %     if ~isempty(app.DCDCCurrent{lapIndex})
                            %         plot(app.currentUIAxes, str2double(app.DCDCCurrent{lapIndex}), 'DisplayName', sprintf('DCDC Lap %d', lapIndex), 'LineWidth',4);
                            %     end
                        end
                    end
                end
            end
            hold(app.currentUIAxes, 'off');
            legend(app.currentUIAxes, 'Location', 'best');
            title(app.currentUIAxes, 'Current vs Measurements');
            xlabel(app.currentUIAxes, 'Measurements');
            ylabel(app.currentUIAxes, 'Current (A)');
        end

        function updateVoltagePlot(app)
            cla(app.voltageUIAxes);  % Clear the current axes
            hold(app.voltageUIAxes, 'on');
            checkedLapNodes = app.LapTree.CheckedNodes;  % Get the list of checked nodes from the LapTree
            checkedVoltageParameters = app.VoltageTree.CheckedNodes;  % Get the list of checked voltage parameters

            % Loop over each checked lap node
            for i = 1:numel(checkedLapNodes)
                lapNode = checkedLapNodes(i);  % Get each checked lap node
                lapIndex = lapNode.NodeData;  % Assuming NodeData stores the corresponding lap index

                % Ensure lapIndex is valid and within bounds
                if isnumeric(lapIndex) && lapIndex > 0 && lapIndex <= length(app.velocity)
                    % Check if the lap data exists and is not empty

                    % Loop over each checked parameter node
                    for j = 1:numel(checkedVoltageParameters)
                        parameterNode = checkedVoltageParameters(j);
                        parameterIndex = parameterNode.NodeData;  % NodeData here should correspond to the parameter index

                        % switch parameterIndex
                        %     case 1  % Supercaps Overall Voltage
                        %         if ~isempty(app.superCapsOverallVoltage{lapIndex})
                        %             plot(app.voltageUIAxes, str2double(app.superCapsOverallVoltage{lapIndex}), 'DisplayName', sprintf('SCO Lap %d', lapIndex), 'LineWidth',4);
                        %         end
                        %     case 2  % Before Motor Control Voltage
                        %         if ~isempty(app.beforeMotorControlVoltage{lapIndex})
                        %             plot(app.voltageUIAxes, str2double(app.beforeMotorControlVoltage{lapIndex}), 'DisplayName', sprintf('BMC Lap %d', lapIndex), 'LineWidth',4);
                        %         end
                        %     case 3  % DC DC Voltage
                        %         if ~isempty(app.DCDCVoltage{lapIndex})
                        %             plot(app.voltageUIAxes, str2double(app.DCDCVoltage{lapIndex}), 'DisplayName', sprintf('DCDC Lap %d', lapIndex), 'LineWidth',4);
                        %         end
                        % end
                    end
                end
            end
            hold(app.voltageUIAxes, 'off');
            legend(app.voltageUIAxes, 'Location', 'best');
            title(app.voltageUIAxes, 'Voltage vs Measurements');
            xlabel(app.voltageUIAxes, 'Measurements');
            ylabel(app.voltageUIAxes, 'Voltage (V)');
        end


        function handleNewLap(app)
            lapNumber = app.LapField.Value;
            % Ensure a node for the new lap exists
            if length(app.LapTree.Children) < lapNumber
                newNode = uitreenode(app.LapTree, 'Text', sprintf('Lap %d', lapNumber), 'NodeData', lapNumber);
                %newNode.Checked = false;  % Default new laps to be visible
                updateVelocityPlot(app);  % Update the plot to include the new lap
            end
        end

        function updateTimer(app)
            % Calculate elapsed time
            elapsed = toc(app.StartTime);
            minutes = floor(elapsed / 60);
            seconds = floor(mod(elapsed, 60));

            % Update the textbox
            app.TimerEditField.Value = sprintf('%02d:%02d', minutes, seconds);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Query available serial ports
            serialInfo = serialportlist("available");
            app.serialPort.Items = serialInfo;
            if isempty(serialInfo)
                app.serialPort.Items = {'No Ports Available'};
                app.serialPort.Enable = 'off';
            else
                app.serialPort.Enable = 'on';
            end
            app.myTimer = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(src, event)myTimerCallback(app, src, event)); % Specify the callback function
            % Start the timer
            start(app.myTimer);
            app.TimerObject = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1, ... % Update every second
                'TimerFcn', @(~,~)updateTimer(app));
        end

        % Value changed function: serialPort
        function serialPortValueChanged(app, event)
            value = app.serialPort.Value;
            disp(['You selected: ', value]);
        end

        % Value changed function: Baudrate
        function BaudrateValueChanged(app, event)
            value = app.Baudrate.Value;
            disp(['You selected: ', value]);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Close previous connections if any
            if ~isempty(app.serialDevice)
                configureCallback(app.serialDevice, "off");
                delete(app.serialDevice);
                app.serialDevice = [];
            end

            % Establish new serial connection
            try
                app.serialDevice = serialport(app.serialPort.Value, str2double(app.Baudrate.Value));
                configureTerminator(app.serialDevice, "LF");
                configureCallback(app.serialDevice, "terminator", @(src, event)myTimerCallback(app, src, event));
                app.dataArray = []; % Clear existing data
                plot(app.velUIAxes, app.dataArray); % Initialize the plot
                disp("Connection Successful");
            catch e
                disp(['Error Connecting to Serial Device: ', e.message]);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop the timer
            %stop(app.myTimer);
    
            % Delete the timer
            delete(app.myTimer);
    
            % Delete the app
            delete(app);
            
        end

        % Callback function: LapTree
        function LapTreeCheckedNodesChanged(app, event)
            % Call updatePlot to refresh the plot based on the new checkbox state
            disp('Checkbox changed');
            updateVelocityPlot(app);
            updateCurrentPlot(app);
            updateVoltagePlot(app);
        end

        % Button pushed function: StartTimerButton
        function StartTimerButtonPushed(app, event)
            if isempty(app.Timer) || strcmp(app.Timer.Running, 'off')
                % Create a timer if it does not exist or is not running
                app.Timer = timer(...
                    'ExecutionMode', 'fixedRate', ... % Run timer repeatedly
                    'Period', 1, ... % Set timer period to 1 second
                    'TimerFcn', @(src, event) updateElapsedTime(app), ... % Update function
                    'StartDelay', 0); % No delay when starting
                start(app.Timer); % Start the timer
                %app.StartStopButton.Text = 'Stop'; % Change button text to 'Stop'
            else
                stop(app.Timer); % Stop the timer
                %app.StartStopButton.Text = 'Start'; % Change button text to 'Start'
            end

            if isempty(app.TimerObject) || strcmp(app.TimerObject.Running, 'off')
                app.StartTime = tic; % Store start time
                start(app.TimerObject); % Start the timer
            else
                stop(app.TimerObject); % Stop the timer
                app.TimerTextBox.Value = '00:00'; % Reset the textbox
            end
        end

        % Callback function: VoltageTree
        function VoltageTreeCheckedNodesChanged(app, event)
            disp('Voltage checkbox changed');
            updateCurrentPlot(app);
            updateVoltagePlot(app);
        end

        % Callback function: CurrentTree
        function CurrentTreeCheckedNodesChanged(app, event)
            disp('Current checkbox changed');
            updateCurrentPlot(app);
            updateVoltagePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.velUIAxes = uiaxes(app.UIFigure);
            title(app.velUIAxes, 'Velocity vs Measurements')
            xlabel(app.velUIAxes, 'Measurements')
            ylabel(app.velUIAxes, 'Velocity (mm/s)')
            zlabel(app.velUIAxes, 'Z')
            app.currentUIAxes = uiaxes(app.UIFigure);
            title(app.currentUIAxes, 'Current vs Measurements')
            xlabel(app.currentUIAxes, 'Measurements')
            ylabel(app.currentUIAxes, 'Current (A)')
            zlabel(app.currentUIAxes, 'Z')
            app.voltageUIAxes = uiaxes(app.UIFigure);
            title(app.voltageUIAxes, 'Voltage vs Measurements')
            xlabel(app.voltageUIAxes, 'Measurements')
            ylabel(app.voltageUIAxes, 'Voltage (V)')
            zlabel(app.voltageUIAxes, 'Z')
            app.BaudrateDropDownLabel = uilabel(app.UIFigure);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.PortLabel = uilabel(app.UIFigure);
            app.PortLabel.Text = 'Port';
            app.serialPort = uidropdown(app.UIFigure);
            app.serialPort.ValueChangedFcn = createCallbackFcn(app, @serialPortValueChanged, true);
            app.Baudrate = uidropdown(app.UIFigure);
            app.Baudrate.Items = {'300', '600', '750', '1200', '2400', '4800', '9600', '19200', '31250', '38400', '57600', '74880', '115200', '230400', '250000', '460800', '500000', '921600', '1000000', '2000000'};
            app.Baudrate.ValueChangedFcn = createCallbackFcn(app, @BaudrateValueChanged, true);
            app.Baudrate.Value = '9600';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.LapEditFieldLabel = uilabel(app.UIFigure);
            app.LapEditFieldLabel.Text = 'Lap';
            app.LapField = uieditfield(app.UIFigure, 'numeric');
            app.LapField.Limits = [1 Inf];
            app.LapField.Value = 1;
            app.VoltageTree = uitree(app.UIFigure, 'checkbox');
            app.SupercapsOverallNode = uitreenode(app.VoltageTree);
            app.SupercapsOverallNode.NodeData = 1;
            app.SupercapsOverallNode.Text = 'Supercaps Overall';
            app.BeforeMotorControlNode = uitreenode(app.VoltageTree);
            app.BeforeMotorControlNode.NodeData = 2;
            app.BeforeMotorControlNode.Text = 'Before Motor Control';
            app.DCDCvoltageNode = uitreenode(app.VoltageTree);
            app.DCDCvoltageNode.NodeData = 3;
            app.DCDCvoltageNode.Text = 'DC DC voltage';
            % Assign Checked Nodes
            app.VoltageTree.CheckedNodesChangedFcn = createCallbackFcn(app, @VoltageTreeCheckedNodesChanged, true);
            app.CurrentTree = uitree(app.UIFigure, 'checkbox');
            app.SupercapsOverallNode_2 = uitreenode(app.CurrentTree);
            app.SupercapsOverallNode_2.NodeData = 1;
            app.SupercapsOverallNode_2.Text = 'Supercaps Overall';
            app.SupercapsChargeNode = uitreenode(app.CurrentTree);
            app.SupercapsChargeNode.NodeData = 2;
            app.SupercapsChargeNode.Text = 'Supercaps Charge';
            app.BeforeMotorControlNode_2 = uitreenode(app.CurrentTree);
            app.BeforeMotorControlNode_2.NodeData = 3;
            app.BeforeMotorControlNode_2.Text = 'Before Motor Control';
            app.DCDCNode = uitreenode(app.CurrentTree);
            app.DCDCNode.NodeData = 4;
            app.DCDCNode.Text = 'DC DC';
            % Assign Checked Nodes
            app.CurrentTree.CheckedNodesChangedFcn = createCallbackFcn(app, @CurrentTreeCheckedNodesChanged, true);
            app.WhichlapshouldbeplottedLabel = uilabel(app.UIFigure);
            app.WhichlapshouldbeplottedLabel.Text = 'Which lap should be plotted';
            app.LapTree = uitree(app.UIFigure, 'checkbox');
            app.Lap1Node = uitreenode(app.LapTree);
            app.Lap1Node.NodeData = 1;
            app.Lap1Node.Text = 'Lap 1';
            % Assign Checked Nodes
            app.LapTree.CheckedNodesChangedFcn = createCallbackFcn(app, @LapTreeCheckedNodesChanged, true);
            app.VoltageplotLabel = uilabel(app.UIFigure);
            app.VoltageplotLabel.Text = 'Voltage plot';
            app.CurrentplotLabel = uilabel(app.UIFigure);
            app.CurrentplotLabel.Text = 'Current plot';
            app.StartTimerButton = uibutton(app.UIFigure, 'push');
            app.StartTimerButton.ButtonPushedFcn = createCallbackFcn(app, @StartTimerButtonPushed, true);
            app.StartTimerButton.Text = 'Start Timer';
            app.TimerEditFieldLabel = uilabel(app.UIFigure);
            app.TimerEditFieldLabel.Text = 'Timer';
            app.TimerEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalRaceEngineer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end